classdef first_and_ten_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        YardLine2EditFieldLabel     matlab.ui.control.Label
        CalibrateScriptButton       matlab.ui.control.Button
        ShowLinesButton             matlab.ui.control.Button
        FirstDownEditField          matlab.ui.control.NumericEditField
        FirstDownEditFieldLabel     matlab.ui.control.Label
        ScrimageLineEditField       matlab.ui.control.NumericEditField
        YardLine2EditField          matlab.ui.control.NumericEditField
        YardLine1EditField          matlab.ui.control.NumericEditField
        YardLine1EditFieldLabel     matlab.ui.control.Label
        EditField_PU2Y              matlab.ui.control.NumericEditField
        EditField_PU2X              matlab.ui.control.NumericEditField
        EditField_PL2Y              matlab.ui.control.NumericEditField
        EditField_PL2X              matlab.ui.control.NumericEditField
        EditField_PU1Y              matlab.ui.control.NumericEditField
        EditField_PU1X              matlab.ui.control.NumericEditField
        EditField_PL1Y              matlab.ui.control.NumericEditField
        EditField_PL1X              matlab.ui.control.NumericEditField
        PixelLower1Label            matlab.ui.control.Label
        PixelUpper1Label            matlab.ui.control.Label
        PixelLower2Label            matlab.ui.control.Label
        PixelUpper2Label            matlab.ui.control.Label
        ScrimageLineEditFieldLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ImageSelectButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        imgPath; % file path for image
        cameraParams; %camera parameters
        img; %stored image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowLinesButton
        function ShowLinesButtonPushed(app, event)
            app.ShowLinesButton.Text = "Processing";
            drawnow;
            
            pixel1 = [app.EditField_PL1X.Value, app.EditField_PL1Y.Value];
            pixel2 = [app.EditField_PU1X.Value, app.EditField_PU1Y.Value];
            pixel3 = [app.EditField_PL2X.Value, app.EditField_PL2Y.Value];
            pixel4 = [app.EditField_PU2X.Value, app.EditField_PU2Y.Value];
            imagePointsCal = [pixel1; pixel2; pixel3; pixel4];

            
            yrdLine1 = app.YardLine1EditField.Value;
            yrdLine2 = app.YardLine2EditField.Value;
            scrimLine = app.ScrimageLineEditField.Value;
            firstLine = app.FirstDownEditField.Value;

            yrdLines = [yrdLine1; yrdLine2; scrimLine; firstLine];

            linedImage = first_and_ten(app.cameraParams, imagePointsCal, yrdLines, app.img);

            imshow(linedImage, 'Parent', app.UIAxes); 

            app.ShowLinesButton.Text = "Show Lines";
        end

        % Button pushed function: CalibrateScriptButton
        function CalibrateScriptButtonPushed(app, event)
            app.CalibrateScriptButton.Text = "Processing";
            drawnow;

            app.cameraParams = calibration_script();
            waitfor(msgbox('Calibration script is completed. Click OK to continue.'));
            
            app.CalibrateScriptButton.Text = "Calibrate Script";
        end

        % Button pushed function: ImageSelectButton
        function ImageSelectButtonPushed(app, event)
            [file,location] = uigetfile('*.jpg','Select an image');
            image = imread(fullfile(location,file));
            
            app.img = image;
            imshow(image, 'Parent', app.UIAxes);             
            app.ImageSelectButton.Text = file;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1111, 1111};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {301, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ScrimageLineEditFieldLabel = uilabel(app.GridLayout2);
            app.ScrimageLineEditFieldLabel.Text = 'Scrimage Line';
            app.PixelUpper2Label = uilabel(app.GridLayout2);
            app.PixelUpper2Label.Text = 'Pixel Upper 2';
            app.PixelLower2Label = uilabel(app.GridLayout2);
            app.PixelLower2Label.Text = 'Pixel Lower 2';
            app.PixelUpper1Label = uilabel(app.GridLayout2);
            app.PixelUpper1Label.Text = 'Pixel Upper 1';
            app.PixelLower1Label = uilabel(app.GridLayout2);
            app.PixelLower1Label.Text = 'Pixel Lower 1';
            app.EditField_PL1X = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PL1Y = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PU1X = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PU1Y = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PL2X = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PL2Y = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PU2X = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_PU2Y = uieditfield(app.GridLayout2, 'numeric');
            app.YardLine1EditFieldLabel = uilabel(app.GridLayout2);
            app.YardLine1EditFieldLabel.Text = 'Yard Line 1';
            app.YardLine1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.YardLine2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.ScrimageLineEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FirstDownEditFieldLabel = uilabel(app.GridLayout2);
            app.FirstDownEditFieldLabel.Text = 'First Down';
            app.FirstDownEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ShowLinesButton = uibutton(app.GridLayout2, 'push');
            app.ShowLinesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLinesButtonPushed, true);
            app.ShowLinesButton.Text = 'Show Lines';
            app.CalibrateScriptButton = uibutton(app.GridLayout2, 'push');
            app.CalibrateScriptButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateScriptButtonPushed, true);
            app.CalibrateScriptButton.Text = 'Calibrate Script';
            app.YardLine2EditFieldLabel = uilabel(app.GridLayout2);
            app.YardLine2EditFieldLabel.Text = 'Yard Line 2';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageSelectButton = uibutton(app.GridLayout3, 'push');
            app.ImageSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSelectButtonPushed, true);
            app.ImageSelectButton.Text = 'Image Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_and_ten_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end